classdef dfInspectRawData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AudioTime                   matlab.ui.control.UIAxes
        MicrophoneDataListBoxLabel  matlab.ui.control.Label
        TrialListBox                matlab.ui.control.ListBox
        SelectFilesButton           matlab.ui.control.Button
        FilesPathEditFieldLabel     matlab.ui.control.Label
        FilesPathEditField          matlab.ui.control.EditField
        curSessLabel                matlab.ui.control.Label
        AudioSpectrum               matlab.ui.control.UIAxes
    end


    properties (Access = private)
        defaultPath   % Where to start looking for recorded data
        fileName
        filePath      % Where the selected recorded data file is
        fullFilePath
        AllRecVar     % All variables and data structures saved as part of a recorded run.
        AudapData     % Data set recorded by Audapter
        
        curSess       % Which Subject, and run
        numTrial      % number of trials
        trialNames    % Names of trials
        fs
        
        curTrialName
        curMicData
    end

    methods (Access = private)
    
        function drawCurTrial_Time(app) 
            audioMic = app.curMicData;
            
            trialLen  = length(audioMic);
            trialTime = trialLen/app.fs;
            time      = linspace(0, trialTime, trialLen);
            
            plot(app.AudioTime, time, audioMic)
            app.AudioTime.Title.String = app.curTrialName;
            app.AudioTime.XLim         = [0 5];
        end
        
        function drawCurTrial_Spect(app)
            audioMic = app.curMicData;
            
            winSize  = 0.005*app.fs;
            nOverLap = winSize*0.5;
            NFFT     = max(256, 2^nextpow2(winSize));
            
            [s, f, t, p] = spectrogram(audioMic, hamming(winSize), nOverLap, NFFT, app.fs);
            
            surf(app.AudioSpectrum, t, f, abs(p), 'EdgeColor', 'none');
            app.AudioSpectrum.XLim = [0 5];
            app.AudioSpectrum.YLim = [0 5000];
            app.AudioSpectrum.View = [0 90];
            app.AudioSpectrum.Colormap = flipud(gray);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function setDefaultParams(app)
            app.defaultPath = 'C:\DATA\Dissociating-Role-of-Feedback-in-Voice-Motor-Control';
            
            app.FilesPathEditField.Value = app.defaultPath;
        end

        % Button pushed function: SelectFilesButton
        function SelectFiles(app, event)
            [app.fileName, app.filePath] = uigetfile(app.defaultPath);
            
            app.fullFilePath = fullfile(app.filePath, app.fileName);
            app.FilesPathEditField.Value = app.fullFilePath;
            
            load(app.fullFilePath); % Loads the variable 'DRF';
            app.AllRecVar = DRF;
            app.AudapData = DRF.rawData;
            
            app.curSess  = DRF.expParam.curSess;
            app.numTrial = DRF.expParam.numTrial;
            app.fs       = DRF.expParam.sRateAnal;
            
            for ii = 1:app.numTrial
                app.trialNames{ii} = ['Trial ' num2str(ii)];
            end
            
            app.curSessLabel.Text  = app.curSess;
            app.TrialListBox.Items = app.trialNames;
            app.TrialListBox.Value = app.trialNames(1);
            loadAudapAudio(app, event)
        end

        % Value changed function: TrialListBox
        function loadAudapAudio(app, event)
            app.curTrialName = app.TrialListBox.Value;
            TrialIdx  = strcmp(app.trialNames, app.curTrialName);
            
            app.curMicData = app.AudapData(TrialIdx).signalIn;
            
            drawCurTrial_Time(app)
            drawCurTrial_Spect(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AudioTime = uiaxes(app.UIFigure);
            title(app.AudioTime, ' ')
            xlabel(app.AudioTime, 'Time (s)')
            app.MicrophoneDataListBoxLabel = uilabel(app.UIFigure);
            app.MicrophoneDataListBoxLabel.Text = 'Microphone Data';
            app.TrialListBox = uilistbox(app.UIFigure);
            app.TrialListBox.Items = {};
            app.TrialListBox.ValueChangedFcn = createCallbackFcn(app, @loadAudapAudio, true);
            app.TrialListBox.Value = {};
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFiles, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FilesPathEditFieldLabel = uilabel(app.UIFigure);
            app.FilesPathEditFieldLabel.Text = 'Files Path:';
            app.FilesPathEditField = uieditfield(app.UIFigure, 'text');
            app.curSessLabel = uilabel(app.UIFigure);
            app.curSessLabel.Text = '';
            app.AudioSpectrum = uiaxes(app.UIFigure);
            title(app.AudioSpectrum, ' ')
            xlabel(app.AudioSpectrum, 'Time (s)')
        end
    end

    methods (Access = public)

        % Construct app
        function app = dfInspectRawData

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @setDefaultParams)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end