classdef dfInspectRawData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AudioTime                       matlab.ui.control.UIAxes
        MicrophoneDataListBoxLabel      matlab.ui.control.Label
        TrialListBox                    matlab.ui.control.ListBox
        SelectFilesButton               matlab.ui.control.Button
        FilesPathEditFieldLabel         matlab.ui.control.Label
        FilesPathEditField              matlab.ui.control.EditField
        curSessLabel                    matlab.ui.control.Label
        AudioSpectrum                   matlab.ui.control.UIAxes
        UpArrow                         matlab.ui.control.Button
        DnArrow                         matlab.ui.control.Button
        PlayAudioButton                 matlab.ui.control.Button
        loadingLabel                    matlab.ui.control.Label
        spectSettingsPanel              matlab.ui.container.Panel
        MinThresholddBLabel             matlab.ui.control.Label
        minThresh                       matlab.ui.control.NumericEditField
        FrequencyResolutionHzLabel      matlab.ui.control.Label
        freqRes                         matlab.ui.control.NumericEditField
        FrequencyBoundsHzLabel          matlab.ui.control.Label
        lwFreqBnField                   matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        upFreqBnField                   matlab.ui.control.NumericEditField
        LeakageLabel                    matlab.ui.control.Label
        leakage                         matlab.ui.control.NumericEditField
        SNRCalculationPanel             matlab.ui.container.Panel
        SignalRMSSetButton              matlab.ui.control.Button
        SignalRMSReadout                matlab.ui.control.Label
        SignalRMSLabel                  matlab.ui.control.Label
        SNRreadout                      matlab.ui.control.Label
        SNRLabel                        matlab.ui.control.Label
        NoiseRMSSetButton               matlab.ui.control.Button
        NoiseRMSLabel                   matlab.ui.control.Label
        NoiseRMSReadout                 matlab.ui.control.Label
        LateWarning                     matlab.ui.control.Label
        VoiceBreakWarning               matlab.ui.control.Label
        ExcludeButton                   matlab.ui.control.Button
        TrialCounter                    matlab.ui.control.Label
        ExcludeTextBox                  matlab.ui.control.TextArea
        f0ViewButton                    matlab.ui.control.Button
        SaveExclusionNotesButton        matlab.ui.control.Button
        SaveLabel                       matlab.ui.control.Label
        DisplayQualityCheckTableButton  matlab.ui.control.Button
    end


    properties (Access = private)
        
        % Path Information for Loading Data and Saving Results
        defaultPath   % Defualt directory for recorded data
        fullFilePath  % File path for the data file to be opened
        
        % Loaded Data 
        DRFStruct     % All variables and data structures saved as part of a recorded run.
        
        fs            % Sampling rate of the recording
        frameLen
        curSess       % Which Subject, and run
        numTrial      % number of trials
        trialLen
        trialLenP
        trialNames    % Names of trials
        trialType     % Control (0)/ Perturbed (1)
        expTrig
        auTrig
        allTrialRes
        f0
        gender
        f0Range
        
        qualCheckTable
        qualCheckTableWidths
        
        incTrialInfo
        curIncInfo
        
        curTrialName
        curTrialIdx
        curTrialRes
        curPertVis
        
        spectMinThreshold
        spectFreqResolution
        spectFrequencyLimits
        spectFrequencySteps
        spectLeakage
        
        allNoiseRMS
        allSignaRMS
        allTrialSNR
        
        spectZoomState
        spectZoomLim
        spectZoomSteps
        
        curFreqLim
        curFreqSteps
        
        colorDefault
        colorRed
        colorBlack
    end

    methods (Access = private)
        
        function confirmUseableFile(app)
            if isfile(app.fullFilePath)
                loadAudapFiles(app, 0)
            else
                fprintf('Not a useable file\n')
            end
        end
    
        function loadAudapFiles(app, event)
            
            app.FilesPathEditField.Value = app.fullFilePath;
            
            % Load the Files 
            app.loadingLabel.Text = 'LOADING...';
            load(app.fullFilePath); % Loads the variable 'DRF';
            app.loadingLabel.Text = '';
            
            % Organize the Files
            app.DRFStruct = DRF;
            
            rawData  = DRF.rawData;
            expParam = DRF.expParam;
            DAQin    = DRF.DAQin;
            
            app.fs         = expParam.sRateAnal;
            app.frameLen   = expParam.frameLenDown;
            app.curSess    = expParam.curSess;
            app.numTrial   = expParam.numTrial;
            app.trialLen   = 5;
            app.trialLenP  = app.trialLen*app.fs;
            app.f0         = expParam.f0b;
            app.gender     = expParam.gender;
            
            identifyf0Range(app)
            
            if isfield(expParam, 'trialType')
                app.trialType = expParam.trialType;
            else
                app.trialType = zeros(app.numTrial, 1);
            end
            
            if isfield(expParam, 'trigs')
                app.expTrig = expParam.trigs(:,:,1);
                app.auTrig  = expParam.trigs(:,:,3);
            else
                app.expTrig = repmat([0 0], app.numTrial, 1);
                app.auTrig  = repmat([0 0], app.numTrial, 1);
            end
            
            % Is there a field for SEQAudFB? Sometimes AudFB changes between trials
            if isfield(expParam, 'SeqAudFB')
                SeqAudFB   = expParam.SeqAudFB;
                SeqAudFBSw = expParam.SeqAudFBSw;
            else
                SeqAudFB      = cell(1, app.numTrial);
                [SeqAudFB{:}] = deal(expParam.AudFB);
                SeqAudFBSw    = repmat(expParam.AudFBSw, 1, app.numTrial);
            end
            
            An.expType  = expParam.expType;
            An.sRate    = app.fs;
            An.frameLen = expParam.frameLenDown;
            
            app.allTrialRes = [];
            app.trialNames  = {};
            for ii = 1:app.numTrial
                append = '';
                if app.trialType(ii) == 1
                    append = '***';
                end              
                app.trialNames{ii} = ['Trial ' num2str(ii) append];
                
                % Consider the data from this trial
                data = rawData(ii);
                
                trialVar.rawMic  = data.signalIn;
                trialVar.rawHead = data.signalOut;
                trialVar.rms     = data.rms(:, 1);
                trialVar.auTrigs = app.auTrig(ii, :);
                trialVar.AudFB   = SeqAudFBSw(ii);
                
                trialVar = setNIDAQTrialVars(app, expParam, DAQin, ii, trialVar);
                
                MH = MicHeadAlignProcess(An, trialVar);
                app.allTrialRes = cat(1, app.allTrialRes, MH);
            end
            
            generateQualCheckTable(app)
            loadInclusionNotes(app)
            
            app.curSessLabel.Text  = app.curSess;
            app.TrialListBox.Items = app.trialNames;
            app.TrialListBox.Value = app.trialNames(1);
            
            app.allNoiseRMS = zeros(app.numTrial, 1);
            app.allSignaRMS = zeros(app.numTrial, 1);
            app.allTrialSNR = zeros(app.numTrial, 1);
            
            switchCurAudio(app, event)
        end
        
        function trialVar = setNIDAQTrialVars(app, expParam, DAQIn, ii, trialVar)
            % trialVar = setNIDAQTrialVars(niAn, auAn, ii, trialVar) is a simple
            % function to set up the fields needed to describe the nidaq behavior.
            % Sometimes NIDAQ data was not recorded, and we want the MHAlignProcess to
            % still function correctly. This sets default values in that case.
            
            if isfield(expParam, 'sRateQ')
                trialVar.fsNI        = expParam.sRateQ;
                trialVar.trialTimeNI = expParam.trialLen;
                trialVar.numSampNI   = trialVar.fsNI*trialVar.trialTimeNI;
                trialVar.timeNI      = linspace(0, trialVar.trialTimeNI, trialVar.numSampNI);
                trialVar.rawMicNI    = DAQIn(:, 5, ii);          % Microphone (NIDAQ)
                trialVar.pressureNI  = DAQIn(:, 4, ii);
                
                trialVar.expTrigsNI    = [0 0]; % Not relevant for app, but relevant for MHAlignProcess
                trialVar.pressureTrigs = [0 0]; % Not relevant for app, but relevant for MHAlignProcess
                trialVar.presLagTimes  = [0 0]; % Not relevant for app, but relevant for MHAlignProcess
                trialVar.presRiseTimes = [0 0]; % Not relevant for app, but relevant for MHAlignProcess
            else
                trialVar.fsNI        = 8000;
                trialVar.trialTimeNI = 4;
                trialVar.numSampNI   = trialVar.trialTimeNI*trialVar.fsNI;
                trialVar.timeNI      = linspace(0, trialVar.trialTimeNI, trialVar.numSampNI);
                trialVar.rawMicNI    = resample(trialVar.rawMic, trialVar.fsNI, app.fs);
                trialVar.pressureNI  = zeros(size(trialVar.timeNI));
                trialVar.expTrigsNI    = [0 0];
                trialVar.pressureTrigs = [0 0];
                trialVar.presLagTimes  = [0 0];
                trialVar.presRiseTimes = [0 0];
            end
        end
        
        function identifyf0Range(app)
            
            defaultMale   = [75 300];
            defaultFemale = [100 500];           
            switch app.gender
                case 'male'
                    if (app.f0/2) < defaultMale(1)
                        app.f0Range = [25 250];
                    else
                        app.f0Range = defaultMale;
                    end
                    f0Steps = 10;
               
                case 'female'
                    if (app.f0*2) > defaultFemale(2)
                        app.f0Range = [200 600];
                    else
                        app.f0Range = defaultFemale;
                    end
                    f0Steps = 9;
            end            
            app.spectZoomLim   = app.f0Range;
            app.spectZoomSteps = linspace(app.f0Range(1), app.f0Range(2), f0Steps);
        end
        
        function generateQualCheckTable(app)
            
            vars = {'Num', 'AuNiDelays', 'VOnsetTime', 'VoiceLate', 'VoiceBreak', 'RecShort'};
            varTypes = {'double', 'double', 'double', 'double', 'double', 'double'};
            numVars = length(vars);
            
            app.qualCheckTable = table('size', [app.numTrial, numVars], 'VariableTypes', varTypes);
            app.qualCheckTable.Properties.VariableNames = vars;
            app.qualCheckTableWidths = {35 85 85 68 68 65};       
            
            for ii = 1:app.numTrial
                trialRes = app.allTrialRes(ii);
                
                app.qualCheckTable.Num(ii)        = ii;
                app.qualCheckTable.AuNiDelays(ii) = trialRes.AuNIDelay;
                app.qualCheckTable.VOnsetTime(ii) = trialRes.voiceOnsetT;
                app.qualCheckTable.VoiceLate(ii)  = trialRes.voiceOnsetLate;
                app.qualCheckTable.VoiceBreak(ii) = trialRes.breakOccured;
                app.qualCheckTable.RecShort(ii)   = trialRes.tooShort;
            end
        end
        
        function loadInclusionNotes(app)
            if isfield(app.DRFStruct, 'incTrialInfo')
                app.incTrialInfo = app.DRFStruct.expParam.incTrialInfo;
            else
                app.incTrialInfo = struct();
                for ii = 1:app.numTrial
                    app.incTrialInfo(ii).include   = 1;
                    app.incTrialInfo(ii).trialNote = {''};
                end
            end
            resetTrialCounter(app)           
        end
        
        function resetTrialCounter(app)
            numIncludedTrials = sum([app.incTrialInfo.include]);
            
            perKept   = round(100*(numIncludedTrials/app.numTrial), 1);
            trialNote = {'Trials Included:'; [num2str(numIncludedTrials) '/' num2str(app.numTrial) ' (' num2str(perKept) '%)']};
            app.TrialCounter.Text = trialNote;
            
            if perKept < 75
                app.TrialCounter.FontColor = app.colorRed;
            else
                app.TrialCounter.FontColor = app.colorBlack;
            end
        end
        
        function drawQualityNotes(app)
        
            app.LateWarning.Text       = '';
            app.VoiceBreakWarning.Text = '';
            
            if app.qualCheckTable.VoiceLate(app.curTrialIdx) == 1
                app.LateWarning.Text = 'Subject started late';
            end
            
            if app.qualCheckTable.VoiceBreak(app.curTrialIdx) == 1
                app.VoiceBreakWarning.Text = 'Subject had a voice break';
            end
        end
        
        function drawCurTrial_Time(app) 

            MH = app.curTrialRes;
            
            minAudio = round(min(MH.rawMic), 2);
            maxAudio = round(max(MH.rawMic), 2);
            
            pertAx    = MH.auTimesAuNi;
            pertAy    = [2 2];
            pertColor = [0 0 1];
            visOption = {'Off', 'On'};
            
            plot(app.AudioTime, MH.time, MH.rawMic, 'k')
            hold(app.AudioTime,'on')
            
            area(app.AudioTime, pertAx, pertAy, -2, 'FaceColor', pertColor, 'EdgeColor', 'none', 'FaceAlpha', 0.25, 'Visible', visOption{app.curPertVis + 1});
%             plot(app.AudioTime, MH.time, MH.env, 'y')
            plot(app.AudioTime, [MH.voiceOnsetT MH.voiceOnsetT], [-1.1 1.1], 'r--', 'LineWidth', 2)
            hold(app.AudioTime, 'off')
            
            app.AudioTime.Title.String   = app.curTrialName;
            app.AudioTime.Title.FontSize = 18;
            app.AudioTime.XLim         = [0 5];
            app.AudioTime.YLim         = [minAudio maxAudio];
            app.AudioTime.YTick        = [minAudio 0 maxAudio];
        end
        
        function drawCurTrial_Spect(app)

            [p, f, t, f0Trace] = calcSpectrogram(app, app.curTrialRes.rawMic);

            if app.spectZoomState == 0
                surf(app.AudioSpectrum, t, f, 10*log10(p), 'EdgeColor', 'none'); 
                             
                app.AudioSpectrum.View     = [0 90];
                app.AudioSpectrum.Colormap = flipud(gray);
            else
                plot(app.AudioSpectrum, t, f0Trace, 'b')
            end
            app.AudioSpectrum.XLim     = [0 5];
            app.AudioSpectrum.YLim     = app.curFreqLim;
            app.AudioSpectrum.YTick    = app.curFreqSteps;
        end
        
        function [p, f, t, f0Trace] = calcSpectrogram(app, audio)

            [p,f,t] = pspectrum(audio, app.fs, 'spectrogram', ...
                                'MinThreshold',        app.spectMinThreshold, ...
                                'FrequencyResolution', app.spectFreqResolution, ...
                                'FrequencyLimits',     app.spectFrequencyLimits,...
                                'Leakage',             app.spectLeakage);
            
                       
            warning off signal:findpeaks:largeMinPeakHeight
            timeSteps  = length(t);
            freqSteps  = length(f);
            f0RangeIdx = find(f > app.f0Range(1) & f < app.f0Range(2));
            
            pf0Trace = zeros(size(p));
            
            fTraces = zeros(2, timeSteps);
            allPks  = zeros(timeSteps, 1);
            for ii = 1:timeSteps
                fTStep = zeros(freqSteps, 1);
                
                [pks, loc] = findpeaks(p(f0RangeIdx, ii), 'MinPeakHeight', 1e-4);
                numPks = length(pks);
                allPks(ii) = numPks;

                if numPks >= 2
                    fTraces(1, ii) = f(f0RangeIdx(loc(1)));
                    fTraces(2, ii) = f(f0RangeIdx(loc(2)));
                    fTStep(f0RangeIdx(loc(1))) = 1;
                elseif numPks == 1
                    fTraces(1, ii) = f(f0RangeIdx(loc(1)));
                    fTraces(2, ii) = NaN;
                    fTStep(f0RangeIdx(loc(1))) = 1;
                else
                    fTraces(1, ii) = NaN;
                    fTraces(2, ii) = NaN;
                end
                
                pf0Trace(:, ii) = fTStep;
            end
            f0Trace = fTraces(1,:);
        end
        
        function setSpectralSettings(app)
            app.spectMinThreshold    = -80;
            app.spectFreqResolution  = 30;
            app.spectFrequencyLimits = [0 5000];
            app.spectFrequencySteps  = 0:1000:5000;
            app.spectLeakage         = 0.0;
            
            app.spectZoomState       = 0;
            app.spectZoomLim         = app.spectFrequencyLimits;
            app.spectZoomSteps       = app.spectFrequencySteps;
            
            app.curFreqLim   = app.spectFrequencyLimits;
            app.curFreqSteps = app.spectFrequencySteps;
        end

        function switchExcludeColor(app)
            if app.curIncInfo.include == 1
                color = app.colorDefault;
            else
                color = app.colorRed;
            end
            app.ExcludeButton.BackgroundColor = color;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.defaultPath = 'C:\DATA\Dissociating-Role-of-Feedback-in-Voice-Motor-Control';            
            app.FilesPathEditField.Value = app.defaultPath;
            
            app.colorDefault = [0.96 0.96 0.96];
            app.colorRed     = [1 0 0];
            app.colorBlack   = [0 0 0];
            
            setSpectralSettings(app)
            app.minThresh.Value     = app.spectMinThreshold;
            app.freqRes.Value       = app.spectFreqResolution;
            app.lwFreqBnField.Value = app.spectFrequencyLimits(1);
            app.upFreqBnField.Value = app.spectFrequencyLimits(2);
            app.leakage.Value       = app.spectLeakage;
            
            if ~isempty(varargin)
                fullPath = varargin{1};
                if exist(fullPath, 'file') == 0
                    fprintf('No file at the path specified!\n')
                    close(app.UIFigure)
                else
                    app.fullFilePath = fullPath;                   
                    confirmUseableFile(app)
                end
            else
                app.fullFilePath = 'W:\Experiments\Dissociating-Role-of-Feedback-in-Voice-Motor-Control\DRF1\AF2\DRF1AF2DRF.mat';
                confirmUseableFile(app)
            end
        end

        % Button pushed function: SelectFilesButton
        function SelectFiles(app, event)
            [fileName, filePath] = uigetfile(app.defaultPath);
            
            if fileName ~= 0
                app.fullFilePath = fullfile(filePath, fileName);       
                confirmUseableFile(app)
            end
        end

        % Value changed function: TrialListBox
        function switchCurAudio(app, event)
            app.curTrialName = app.TrialListBox.Value;
            trialIdx         = strcmp(app.trialNames, app.curTrialName);
            
            app.curTrialIdx  = trialIdx;
            app.curTrialRes  = app.allTrialRes(trialIdx);     
            app.curIncInfo   = app.incTrialInfo(trialIdx);
            app.curPertVis   = app.trialType(trialIdx);       % Visible or not
            
            app.NoiseRMSReadout.Text  = [num2str(app.allNoiseRMS(trialIdx),2) ' dB'];
            app.SignalRMSReadout.Text = [num2str(app.allSignaRMS(trialIdx),2) ' dB'];
            app.SNRreadout.Text       = [num2str(app.allTrialSNR(trialIdx),2) ' dB'];
            
            switchExcludeColor(app)
            app.ExcludeTextBox.Value = app.curIncInfo.trialNote;
            
            drawQualityNotes(app)
            drawCurTrial_Time(app)
            drawCurTrial_Spect(app)
        end

        % Button pushed function: UpArrow
        function ScrollUpInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= 1
                newtrialIdx = trialIdx - 1;
                app.TrialListBox.Value = app.trialNames(newtrialIdx);
                switchCurAudio(app, event)
            end
        end

        % Button pushed function: DnArrow
        function ScrollDnInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= app.numTrial
                newtrialIdx = trialIdx + 1;
                app.TrialListBox.Value = app.trialNames(newtrialIdx);
                switchCurAudio(app, event)
            end            
        end

        % Button pushed function: PlayAudioButton
        function PlaySelectedAudio(app, event)
            sound(app.curTrialRes.rawMic, app.fs)
        end

        % Value changed function: freqRes, leakage, lwFreqBnField, 
        % minThresh, upFreqBnField
        function updateSpectSettings(app, event)
            
            app.spectMinThreshold       = app.minThresh.Value;
            app.spectFreqResolution     = app.freqRes.Value;
            app.spectFrequencyLimits(1) = app.lwFreqBnField.Value;
            app.spectFrequencyLimits(2) = app.upFreqBnField.Value;
            app.spectLeakage            = app.leakage.Value;
            
            drawCurTrial_Spect(app)
        end

        % Button pushed function: SignalRMSSetButton
        function findTimeRMS(app, event)
            
            
        end

        % Button pushed function: ExcludeButton
        function toggleExcludeTrial(app, event)
            app.curTrialName = app.TrialListBox.Value;
            trialIdx         = strcmp(app.trialNames, app.curTrialName);
            
            if app.curIncInfo.include == 1
                app.curIncInfo.include = 0;
            else
                app.curIncInfo.include = 1;
            end
            app.incTrialInfo(trialIdx) = app.curIncInfo;
            
            switchExcludeColor(app);            
            resetTrialCounter(app)
        end

        % Button pushed function: f0ViewButton
        function toggleZoom(app, event)
            if app.spectZoomState == 1
                app.spectZoomState = 0;
                app.curFreqLim  = app.spectFrequencyLimits;
                app.curFreqSteps = app.spectFrequencySteps;
            else
                app.spectZoomState = 1;
                app.curFreqLim  = app.spectZoomLim;
                app.curFreqSteps = app.spectZoomSteps;
            end
            drawCurTrial_Spect(app)
        end

        % Value changed function: ExcludeTextBox
        function ModifyExcludeText(app, event)
            app.curTrialName = app.TrialListBox.Value;
            trialIdx         = strcmp(app.trialNames, app.curTrialName);
            
            app.curIncInfo.trialNote  = app.ExcludeTextBox.Value;
            app.incTrialInfo(trialIdx) = app.curIncInfo;            
        end

        % Button pushed function: SaveExclusionNotesButton
        function saveDatawNotes(app, event)
            DRF = app.DRFStruct;
            
            DRF.expParam.incTrialInfo = app.incTrialInfo;          
            
            app.SaveLabel.Text = 'Saving...Don''t Exit!!';
            save(app.fullFilePath, 'DRF')
            app.SaveLabel.Text = 'Saved!!';
            pause(3)
            app.SaveLabel.Text = '';
        end

        % Button pushed function: DisplayQualityCheckTableButton
        function displayqcTable(app, event)
            qcTableFig = figure('Color', [1 1 1]);
            set(qcTableFig, 'Position',[10, 50, 430 750],'PaperPositionMode','auto')
            
            uitable(qcTableFig,...
                    'Data', app.qualCheckTable{:,:},...
                    'ColumnName', app.qualCheckTable.Properties.VariableNames,...
                    'ColumnWidth', app.qualCheckTableWidths,...
                    'RowName', app.qualCheckTable.Properties.RowNames,...
                    'Units', 'Normalized',... 
                    'Position', [0, 0, 1, 1]);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AudioTime = uiaxes(app.UIFigure);
            title(app.AudioTime, ' ')
            app.AudioTime.YLim = [-0.25 0.25];
            app.AudioTime.XTick = [];
            app.AudioTime.YTick = [-0.25 0 0.25];
            app.MicrophoneDataListBoxLabel = uilabel(app.UIFigure);
            app.MicrophoneDataListBoxLabel.Text = 'Microphone Data';
            app.TrialListBox = uilistbox(app.UIFigure);
            app.TrialListBox.Items = {};
            app.TrialListBox.ValueChangedFcn = createCallbackFcn(app, @switchCurAudio, true);
            app.TrialListBox.Value = {};
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFiles, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FilesPathEditFieldLabel = uilabel(app.UIFigure);
            app.FilesPathEditFieldLabel.Text = 'Files Path:';
            app.FilesPathEditField = uieditfield(app.UIFigure, 'text');
            app.curSessLabel = uilabel(app.UIFigure);
            app.curSessLabel.Text = '';
            app.AudioSpectrum = uiaxes(app.UIFigure);
            xlabel(app.AudioSpectrum, 'Time (s)')
            app.AudioSpectrum.YLim = [0 5000];
            app.UpArrow = uibutton(app.UIFigure, 'push');
            app.UpArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollUpInList, true);
            app.UpArrow.Icon = 'UpArrow.png';
            app.UpArrow.Text = '';
            app.DnArrow = uibutton(app.UIFigure, 'push');
            app.DnArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollDnInList, true);
            app.DnArrow.Icon = 'DnArrow.png';
            app.DnArrow.Text = '';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySelectedAudio, true);
            app.PlayAudioButton.Icon = 'PlayBtn.png';
            app.PlayAudioButton.Text = 'Play Audio';
            app.loadingLabel = uilabel(app.UIFigure);
            app.loadingLabel.Text = '';
            app.spectSettingsPanel = uipanel(app.UIFigure);
            app.spectSettingsPanel.Title = 'Spectrogram Settings';
            app.MinThresholddBLabel = uilabel(app.spectSettingsPanel);
            app.MinThresholddBLabel.Text = 'Min Threshold (dB)';
            app.minThresh = uieditfield(app.spectSettingsPanel, 'numeric');
            app.minThresh.ValueChangedFcn = createCallbackFcn(app, @updateSpectSettings, true);
            app.minThresh.Value = -50;
            app.FrequencyResolutionHzLabel = uilabel(app.spectSettingsPanel);
            app.FrequencyResolutionHzLabel.Text = {'Frequency '; 'Resolution (Hz)'};
            app.freqRes = uieditfield(app.spectSettingsPanel, 'numeric');
            app.freqRes.ValueChangedFcn = createCallbackFcn(app, @updateSpectSettings, true);
            app.freqRes.Value = 50;
            app.FrequencyBoundsHzLabel = uilabel(app.spectSettingsPanel);
            app.FrequencyBoundsHzLabel.Text = {'Frequency'; ' Bounds (Hz)'};
            app.lwFreqBnField = uieditfield(app.spectSettingsPanel, 'numeric');
            app.lwFreqBnField.ValueChangedFcn = createCallbackFcn(app, @updateSpectSettings, true);
            app.toLabel = uilabel(app.spectSettingsPanel);
            app.toLabel.Text = 'to';
            app.upFreqBnField = uieditfield(app.spectSettingsPanel, 'numeric');
            app.upFreqBnField.ValueChangedFcn = createCallbackFcn(app, @updateSpectSettings, true);
            app.upFreqBnField.Value = 5000;
            app.LeakageLabel = uilabel(app.spectSettingsPanel);
            app.LeakageLabel.Text = 'Leakage';
            app.leakage = uieditfield(app.spectSettingsPanel, 'numeric');
            app.leakage.ValueChangedFcn = createCallbackFcn(app, @updateSpectSettings, true);
            app.leakage.Value = 0.5;
            app.SNRCalculationPanel = uipanel(app.UIFigure);
            app.SNRCalculationPanel.Title = 'SNR Calculation';
            app.SignalRMSSetButton = uibutton(app.SNRCalculationPanel, 'push');
            app.SignalRMSSetButton.ButtonPushedFcn = createCallbackFcn(app, @findTimeRMS, true);
            app.SignalRMSSetButton.Text = 'Set';
            app.SignalRMSReadout = uilabel(app.SNRCalculationPanel);
            app.SignalRMSReadout.Text = '0.0 dB';
            app.SignalRMSLabel = uilabel(app.SNRCalculationPanel);
            app.SignalRMSLabel.Text = 'Signal RMS';
            app.SNRreadout = uilabel(app.SNRCalculationPanel);
            app.SNRreadout.Text = '0.0 dB';
            app.SNRLabel = uilabel(app.SNRCalculationPanel);
            app.SNRLabel.Text = 'SNR';
            app.NoiseRMSSetButton = uibutton(app.SNRCalculationPanel, 'push');
            app.NoiseRMSSetButton.Text = 'Set';
            app.NoiseRMSLabel = uilabel(app.SNRCalculationPanel);
            app.NoiseRMSLabel.Text = 'Noise RMS';
            app.NoiseRMSReadout = uilabel(app.SNRCalculationPanel);
            app.NoiseRMSReadout.Text = '0.0 dB';
            app.LateWarning = uilabel(app.SNRCalculationPanel);
            app.LateWarning.Text = '';
            app.VoiceBreakWarning = uilabel(app.SNRCalculationPanel);
            app.VoiceBreakWarning.Text = '';
            app.ExcludeButton = uibutton(app.UIFigure, 'push');
            app.ExcludeButton.ButtonPushedFcn = createCallbackFcn(app, @toggleExcludeTrial, true);
            app.ExcludeButton.Text = 'Exclude';
            app.TrialCounter = uilabel(app.UIFigure);
            app.TrialCounter.Text = '#/#';
            app.ExcludeTextBox = uitextarea(app.UIFigure);
            app.ExcludeTextBox.ValueChangedFcn = createCallbackFcn(app, @ModifyExcludeText, true);
            app.f0ViewButton = uibutton(app.UIFigure, 'push');
            app.f0ViewButton.ButtonPushedFcn = createCallbackFcn(app, @toggleZoom, true);
            app.f0ViewButton.Text = 'f0 View';
            app.SaveExclusionNotesButton = uibutton(app.UIFigure, 'push');
            app.SaveExclusionNotesButton.ButtonPushedFcn = createCallbackFcn(app, @saveDatawNotes, true);
            app.SaveExclusionNotesButton.Text = 'Save Exclusion Notes';
            app.SaveLabel = uilabel(app.UIFigure);
            app.SaveLabel.Text = '';
            app.DisplayQualityCheckTableButton = uibutton(app.UIFigure, 'push');
            app.DisplayQualityCheckTableButton.ButtonPushedFcn = createCallbackFcn(app, @displayqcTable, true);
            app.DisplayQualityCheckTableButton.Text = {'Display Quality '; 'Check Table'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dfInspectRawData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end