classdef dfParseRawVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        rawVideoView              matlab.ui.control.UIAxes
        TrialsListBoxLabel        matlab.ui.control.Label
        TrialsListBox             matlab.ui.control.ListBox
        UpArrow                   matlab.ui.control.Button
        DnArrow                   matlab.ui.control.Button
        PlayAudioButton           matlab.ui.control.Button
        ParticipantDropDownLabel  matlab.ui.control.Label
        ParticipantDropDown       matlab.ui.control.DropDown
        CurrentDirLabel           matlab.ui.control.Label
        CurrentDirEditField       matlab.ui.control.EditField
        ChangeDirButton           matlab.ui.control.Button
        RunDropDownLabel          matlab.ui.control.Label
        RunDropDown               matlab.ui.control.DropDown
        LoadButton                matlab.ui.control.Button
        StatusText                matlab.ui.control.Label
        FrameLabel                matlab.ui.control.Label
        NextFrameButt             matlab.ui.control.Button
        Next10FrameButt           matlab.ui.control.Button
        Last10FrameButt           matlab.ui.control.Button
        LastFrameButt             matlab.ui.control.Button
        PixelsDistLabel           matlab.ui.control.Label
        CoderIDLabel              matlab.ui.control.Label
        EnterIDEditFieldLabel     matlab.ui.control.Label
        EnterIDEditField          matlab.ui.control.EditField
        SaveCodedTrialsButton     matlab.ui.control.Button
    end


    properties (Access = private)
        appWinSize
        CoderID
        
        % Full Experimental Parameters
        participantList
        runList
        trialNames
        
        defaultDataPath
        defaultResultsPath
        
        % Current Data Conditions
        curDataPath
        curResultsPath
        curPart
        curRun
        curTrialNum
        curTrialName
        curFrame
        
        curPartDataPath
        curPartRawVidPath
        curPartRawAudFile
        curPartRawVidFile
        curPartResultsFile
        curPartEndoFrameFile %Final Output
        
        sRateExp
        allIdxFin
        numTrials
        expAudioM
        trialLenP
        setA
        timef0
        expAudioMf0
        
        vidObj
        vidStr
        
        vidWidth
        vidHeight
        vidFrameR
        
        trialLenF
        setF
        
        nFrames
        
        endoAudio
        endoAfs
        
        endoAudioDN
        trialDelays
        trialRecStTimes
        videoFrameSeg
        videoSeg
        curVidSeg
        endoAudioM
        
        parseTrialT
        
        isDataLoaded
        isExpDataLoaded
        isVidDataLoaded
        
        curSelectPt1
        curSelectPt2
        
        curPtLoc1
        curPtLoc2
        
        fp1
        fp2
        fpDistLine
        
        allOutputTables
    end

    methods (Access = private)
    
        function setDefaults(app)
            app.participantList = {'DRF5', 'DRF9', 'DRF12', 'DRF14', 'DRF19'};
            app.runList         = {'SFL1'};
            
            app.curPart = app.participantList{3};
            app.curRun  = app.runList{1};
            app.curFrame = -10;                 % Renders frame progression inopperative until data is loaded
            app.isDataLoaded    = 0;
            app.isExpDataLoaded = 0;
            app.isVidDataLoaded = 0;
            
            app.curSelectPt1 = 0;
            app.curSelectPt2 = 0;
            
            app.curPtLoc1 = [0 0];
            app.curPtLoc2 = [0 0];
            
            app.defaultDataPath    = 'S:\SteppLab2\SPEECHDATA\Voice\Dissociating-Role-of-Feedback-in-Voice-Motor-Control';
            app.defaultResultsPath = 'C:\Users\djsmith\Documents\MATLAB\dfResults\Results';
        end
        
        function setCurrentDir(app)
        
            app.CurrentDirEditField.Value = app.curDataPath;
        
            app.curPartDataPath = fullfile(app.curDataPath, app.curPart);
            
            app.curPartRawVidPath = fullfile(app.curPartDataPath, 'rawVideo');
            app.curPartRawAudFile = fullfile(app.curPartRawVidPath, [app.curPart ' rawAudio.wav']);
            app.curPartRawVidFile = fullfile(app.curPartRawVidPath, [app.curPart ' rawVideo.avi']);
            
            app.curPartResultsFile   = fullfile(app.curResultsPath, app.curPart, app.curRun, [app.curPart app.curRun 'AllResultsDRF.mat']);
        end
        
        function checkFile(app)
            
            if isfile(app.curPartResultsFile)
                expResultsStat = 1;
                app.StatusText.Text = 'Loading Experimental Results';
                loadExpResults(app)
                app.StatusText.Text = '';
                
                app.isExpDataLoaded = 1;
            else
                expResultsStat = 0;
                app.StatusText.Text = 'Could Not Locate Experimental Results. Please check Dirs';
            end
            
            if isfile(app.curPartRawVidFile) && expResultsStat == 1
                app.StatusText.Text = 'Loading Kay Pentax Audio Data';
                loadRawAudio(app)
                app.StatusText.Text = 'Loading Kay Pentax Video Data';
                loadRawVideo(app)
                app.StatusText.Text = '';
                
                app.isVidDataLoaded = 1;
            else
                app.StatusText.Text = 'Could Not Locate Video Data. Please check Dirs';
            end
            
            if app.isExpDataLoaded == 1 && app.isVidDataLoaded == 1
                app.isDataLoaded = 1;
            end
            
        end
        
        function loadExpResults(app)
            load(app.curPartResultsFile) % Returns res
            
            app.sRateExp    = res.sRate;
            app.allIdxFin   = res.allIdxFin;
            app.numTrials   = length(app.allIdxFin);
            app.expAudioM   = res.audioMinc;
            app.trialLenP   = length(app.expAudioM);
            app.setA        = (1:app.trialLenP) - 1;
            app.timef0      = res.timef0;
            app.expAudioMf0 = res.audioMf0TrialPert;
            
            app.trialNames = {};
            for ii = 1:app.numTrials
                app.trialNames{ii} = ['Trial ' num2str(app.allIdxFin(ii))];
            end
            
            app.TrialsListBox.Items = app.trialNames;
            app.TrialsListBox.Value = app.trialNames(1);
        end
        
        function loadRawAudio(app)
            [app.endoAudio, app.endoAfs] = audioread(app.curPartRawAudFile);
        end
        
        function loadRawVideo(app)
            app.vidObj = VideoReader(app.curPartRawVidFile);
            
            app.vidWidth  = app.vidObj.Width;
            app.vidHeight = app.vidObj.Height;
            app.vidFrameR = app.vidObj.FrameRate;
            app.trialLenF = round(app.trialLenP*(app.vidFrameR/app.sRateExp));
            app.setF      = (1:app.trialLenF) - 1;
            
            app.vidStr = struct('cdata', zeros(app.vidHeight, app.vidWidth, 3, 'uint8'), 'colormap', []);
            
            itrFrames = 0;
            while hasFrame(app.vidObj)
                itrFrames = itrFrames+1;
                app.vidStr(itrFrames).cdata = readFrame(app.vidObj);                
            end

            setViewWindowParams(app)            
        end
        
        function setViewWindowParams(app)
            
            title(app.rawVideoView, []);
            xlabel(app.rawVideoView, []);
            ylabel(app.rawVideoView, []);
            app.rawVideoView.XAxis.TickLabels = {};
            app.rawVideoView.YAxis.TickLabels = {};
            
            app.rawVideoView.XLim = [0 app.vidWidth];
            app.rawVideoView.YLim = [0 app.vidHeight];            
        end
        
        function parseTrials(app)
            
            app.endoAudioDN = resample(app.endoAudio(:,1), app.sRateExp, app.endoAfs);
            
            app.allOutputTables = cell(app.numTrials, 1);

            app.trialDelays     = zeros(app.numTrials, 1);
            app.trialRecStTimes = zeros(app.numTrials, 1);
            app.videoFrameSeg   = zeros(app.numTrials, 2);
            app.videoSeg        = cell(app.numTrials, 1);
            app.endoAudioM      = zeros(app.trialLenP, app.numTrials);
            for ii = 1:app.numTrials
                app.trialDelays(ii)     = xCorrTimeLag(app, app.endoAudioDN, app.expAudioM(:,ii));
                app.trialRecStTimes(ii) = round(app.trialDelays(ii)/app.sRateExp, 2);
                app.endoAudioM(:,ii)    = app.endoAudioDN(app.setA + app.trialDelays(ii));
                
                videoFrameSt = floor(app.trialRecStTimes(ii)*app.vidFrameR);
                videoFrameSp = videoFrameSt + app.trialLenF;
                
                app.videoFrameSeg(ii, :) = [videoFrameSt videoFrameSp];                
                app.videoSeg{ii} = app.vidStr(videoFrameSt:videoFrameSp);
                app.nFrames = length(app.videoSeg{ii});
                
                % Set up the Output Table that will record Fiduciary points
                % and pixel distances
                app.allOutputTables{ii} = setTrialOutputTable(app);
            end
            app.parseTrialT = table(app.trialRecStTimes, app.videoFrameSeg(:, 1), 'VariableNames', {'Time', 'Frame'});
            
        end
        
        function pointLag = xCorrTimeLag(app, sig1, sig2)
            % xCorrTimeLag(sig1, sig2, fs) calculates the lag between two (seemingly) 
            % identical time based signals. 
            %
            % if timeLag is positive, then sig1 leads sig2. 
            % if timeLag is negative, then sig1 lags sig2.
            
            % Simple crosscorrelation between two signals
            % Finds the largest peak of the result
            [r, lags]    = xcorr(sig1, sig2);
            [~, peakInd] = max(r);
            pointLag     = lags(peakInd);
        end
        
        function setFrameCounterLabel(app)
            counterLabel = sprintf('Frame\n%i/%i', app.curFrame, app.nFrames);
            app.FrameLabel.Text = counterLabel;
        end
        
        function dispFrame(app)
            image(app.rawVideoView, app.curVidSeg(app.curFrame).cdata)
        end
        
        function setFiduciaryPoints(app)
           hold(app.rawVideoView, 'on')
           app.fpDistLine = plot(app.rawVideoView, [app.curPtLoc1(1) app.curPtLoc2(1)], [app.curPtLoc1(2) app.curPtLoc2(2)], 'b', 'LineWidth', 2);
           app.fp1 = plot(app.rawVideoView, app.curPtLoc1(1), app.curPtLoc1(2), 'm*');
           app.fp2 = plot(app.rawVideoView, app.curPtLoc2(1), app.curPtLoc2(2), 'g*');
           hold(app.rawVideoView, 'off')
           
           calcPixelDist(app)
        end
        
        function moveFiduciaryPoints(app)
            set(app.fp1, 'XData', app.curPtLoc1(1),...
                         'YData', app.curPtLoc1(2),...
                         'Visible', 'On');
            
            set(app.fp2, 'XData', app.curPtLoc2(1),...
                         'YData', app.curPtLoc2(2),...
                         'Visible', 'On');
             
            set(app.fpDistLine, 'XData', [app.curPtLoc1(1) app.curPtLoc2(1)],...
                                'YData', [app.curPtLoc1(2) app.curPtLoc2(2)])
            
            calcPixelDist(app)
        end
        
        function calcPixelDist(app)
            XVals = app.fpDistLine.XData;
            YVals = app.fpDistLine.YData;
            
            dist = sqrt((diff(XVals))^2 + (diff(YVals))^2);
            app.PixelsDistLabel.Text = [num2str(round(dist,2)) ' Pixels'];
        end
        
        function saveCodedDataset(app)
        
            app.curPartEndoFrameFile = fullfile(app.curResultsPath, app.curPart, app.curRun, [app.curPart app.curRun 'EndoFrameMeasures' app.CoderID '.mat']);

            CodedEndoFrameDataSet = 'Butts';
            save(app.curPartEndoFrameFile, 'CodedEndoFrameDataSet')
        end
        
        function defaultOutputTable = setTrialOutputTable(app)
            defaultOutputTable = table('Size', [app.nFrames, 5],...
                                       'VariableTypes', {'double', 'double', 'double', 'double', 'double'},...
                                       'VariableNames', {'FidPt1X', 'FidPt1Y', 'FidPt2X', 'FidPt2Y', 'Dist'});
                                       
                                           
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            matlab.video.read.UseHardwareAcceleration('off')
             
            app.StatusText.Text = '';
            app.appWinSize = app.UIFigure.Position(3:4);
            
            % Initialize the menus/fields with experimental specific strings
            setDefaults(app)
            
            app.ParticipantDropDown.Items = app.participantList;
            app.ParticipantDropDown.Value = app.curPart;
            
            app.RunDropDown.Items = app.runList;
            app.RunDropDown.Value = app.curRun;
            
            app.curDataPath     = app.defaultDataPath;
            app.curResultsPath  = app.defaultResultsPath;
            setCurrentDir(app)
        end

        % Button pushed function: ChangeDirButton
        function ChangeDir(app, event)
            filePath = uigetdir(app.defaultDataPath);
            
            if filePath ~= 0
                app.curDataPath = filePath;
                setCurrentDir(app)
            end
        end

        % Button pushed function: LoadButton
        function LoadRawData(app, event)
            % The load button has been pushed. Let's go find the raw data
            % files, and set them up to be used in the app
            
            % Check that files are there.
            % Also load the files
            checkFile(app)
            
            if app.isDataLoaded == 1 %If the data was loaded successfully
                % Parse the large video file into individual trial videos
                parseTrials(app)
                
                % Switch to first trial
                switchCurTrial(app, 0)
            end
        end

        % Value changed function: TrialsListBox
        function switchCurTrial(app, event)
            app.curTrialName = app.TrialsListBox.Value;
            trialIdx         = strcmp(app.trialNames, app.curTrialName);
            
            app.curVidSeg   = app.videoSeg{trialIdx};
            
            app.curFrame    = 1;
            app.nFrames     = length(app.curVidSeg);
            
            setFrameCounterLabel(app)
            dispFrame(app)
            setFiduciaryPoints(app)
        end

        % Button pushed function: UpArrow
        function ScrollUpInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= 1
                newtrialIdx = trialIdx - 1;
                app.TrialsListBox.Value = app.trialNames(newtrialIdx);
                switchCurTrial(app, event)
            end
        end

        % Button pushed function: DnArrow
        function ScrollDownInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= app.numTrials
                newtrialIdx = trialIdx + 1;
                app.TrialsListBox.Value = app.trialNames(newtrialIdx);
                switchCurTrial(app, event)
            end
        end

        % Button pushed function: PlayAudioButton
        function PlayVideo(app, event)
            curFrames = length(app.curVidSeg);
            numFrameSecs = 1/app.vidFrameR;
            
            for ii = 1:curFrames
                
                image(app.rawVideoView, app.curVidSeg(ii).cdata)
                pause(numFrameSecs)
            end
        end

        % Button pushed function: Last10FrameButt, LastFrameButt, 
        % Next10FrameButt, NextFrameButt
        function MoveFrame(app, event)

            whichButt = event.Source.Text;
            if strcmp(whichButt, '>')
                inc = 1;
            elseif strcmp(whichButt, '<')
                inc = -1;
            elseif strcmp(whichButt, '10 <')
                inc = -10;
            elseif strcmp(whichButt, '> 10')
                inc = 10;
            else
                inc = 0;
            end
            
            nextFrame = app.curFrame + inc;            
            if nextFrame >= 1 && nextFrame <= app.nFrames
                app.curFrame = nextFrame;
            
                setFrameCounterLabel(app)
                dispFrame(app)
                setFiduciaryPoints(app)
            end
        end

        % Key press function: UIFigure
        function KeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, '1')
                app.curSelectPt1 = 1;
            elseif strcmp(key, '2')
                app.curSelectPt2 = 1;
            end
            
        end

        % Key release function: UIFigure
        function KeyRelease(app, event)
            key = event.Key;
            
            if strcmp(key, '1')
                app.curSelectPt1 = 0;
            elseif strcmp(key, '2')
                app.curSelectPt2 = 0;
            end
        end

        % Window button down function: UIFigure
        function CheckCurCursor(app, event)
            
            if app.isDataLoaded == 1
                curPointClick = app.rawVideoView.CurrentPoint;
                curPointClick = curPointClick(1,1:2);
                if (0 <= curPointClick(1)) && (app.vidWidth >= curPointClick(1)) &&... % So that it does not trigger beyond the video space
                   (0 <= curPointClick(2)) && (app.vidHeight >= curPointClick(2))
                    
                   if app.curSelectPt1 == 1
                       app.curPtLoc1 = curPointClick;
                       moveFiduciaryPoints(app)
                   elseif app.curSelectPt2 == 1
                       app.curPtLoc2 = curPointClick;
                       moveFiduciaryPoints(app)
                   end
                end
            end
        end

        % Value changed function: EnterIDEditField
        function ChangeCoderID(app, event)
            value = app.EnterIDEditField.Value;
            
            app.CoderID = value;
            app.CoderIDLabel.Text = ['Coder ID: ' value];
            app.EnterIDEditField.Value = '';
        end

        % Button pushed function: SaveCodedTrialsButton
        function checkSaveConditions(app, event)
            if isempty(app.CoderID)
                msgbox('Please enter a coder ID before saving the dataset!')
            else
                saveCodedDataset(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @CheckCurCursor, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @KeyRelease, true);
            app.rawVideoView = uiaxes(app.UIFigure);
            app.rawVideoView.XTickLabel = {''; ''};
            app.rawVideoView.YTickLabel = '';
            app.TrialsListBoxLabel = uilabel(app.UIFigure);
            app.TrialsListBoxLabel.Text = 'Trials';
            app.TrialsListBox = uilistbox(app.UIFigure);
            app.TrialsListBox.Items = {' '};
            app.TrialsListBox.ValueChangedFcn = createCallbackFcn(app, @switchCurTrial, true);
            app.TrialsListBox.Value = {};
            app.UpArrow = uibutton(app.UIFigure, 'push');
            app.UpArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollUpInList, true);
            app.UpArrow.Icon = 'UpArrow.png';
            app.UpArrow.Text = '';
            app.DnArrow = uibutton(app.UIFigure, 'push');
            app.DnArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollDownInList, true);
            app.DnArrow.Icon = 'DnArrow.png';
            app.DnArrow.Text = '';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVideo, true);
            app.PlayAudioButton.Icon = 'PlayBtn.png';
            app.PlayAudioButton.Text = 'Play Audio';
            app.ParticipantDropDownLabel = uilabel(app.UIFigure);
            app.ParticipantDropDownLabel.Text = 'Participant';
            app.ParticipantDropDown = uidropdown(app.UIFigure);
            app.ParticipantDropDown.Items = {'Participant 1', 'Participant 2', 'Participant 3', 'Participant 4'};
            app.ParticipantDropDown.Value = 'Participant 1';
            app.CurrentDirLabel = uilabel(app.UIFigure);
            app.CurrentDirLabel.Text = 'Current Dir:';
            app.CurrentDirEditField = uieditfield(app.UIFigure, 'text');
            app.ChangeDirButton = uibutton(app.UIFigure, 'push');
            app.ChangeDirButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDir, true);
            app.ChangeDirButton.Text = 'Change Dir';
            app.RunDropDownLabel = uilabel(app.UIFigure);
            app.RunDropDownLabel.Text = 'Run';
            app.RunDropDown = uidropdown(app.UIFigure);
            app.RunDropDown.Items = {'Run 1', 'Run 2'};
            app.RunDropDown.Value = 'Run 1';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRawData, true);
            app.LoadButton.Text = 'Load';
            app.StatusText = uilabel(app.UIFigure);
            app.StatusText.Text = '';
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = {'Frame '; '#/#'};
            app.NextFrameButt = uibutton(app.UIFigure, 'push');
            app.NextFrameButt.ButtonPushedFcn = createCallbackFcn(app, @MoveFrame, true);
            app.NextFrameButt.Text = '>';
            app.Next10FrameButt = uibutton(app.UIFigure, 'push');
            app.Next10FrameButt.ButtonPushedFcn = createCallbackFcn(app, @MoveFrame, true);
            app.Next10FrameButt.Text = '> 10';
            app.Last10FrameButt = uibutton(app.UIFigure, 'push');
            app.Last10FrameButt.ButtonPushedFcn = createCallbackFcn(app, @MoveFrame, true);
            app.Last10FrameButt.Text = '10 <';
            app.LastFrameButt = uibutton(app.UIFigure, 'push');
            app.LastFrameButt.ButtonPushedFcn = createCallbackFcn(app, @MoveFrame, true);
            app.LastFrameButt.Text = '<';
            app.PixelsDistLabel = uilabel(app.UIFigure);
            app.PixelsDistLabel.Text = '';
            app.CoderIDLabel = uilabel(app.UIFigure);
            app.CoderIDLabel.Text = 'Coder ID:';
            app.EnterIDEditFieldLabel = uilabel(app.UIFigure);
            app.EnterIDEditFieldLabel.Text = 'Enter ID:';
            app.EnterIDEditField = uieditfield(app.UIFigure, 'text');
            app.EnterIDEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeCoderID, true);
            app.SaveCodedTrialsButton = uibutton(app.UIFigure, 'push');
            app.SaveCodedTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @checkSaveConditions, true);
            app.SaveCodedTrialsButton.Text = 'Save Coded Trials';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dfParseRawVideo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end